classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        startmotorCheckBox     matlab.ui.control.CheckBox
        BackgroundImageButton  matlab.ui.control.Button
        ArrangeBoardButton     matlab.ui.control.Button
        ReadBoardButton        matlab.ui.control.Button
        Label_8                matlab.ui.control.Label
        Label_7                matlab.ui.control.Label
        Label_6                matlab.ui.control.Label
        Label_5                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Lamp_8                 matlab.ui.control.Lamp
        Lamp_7                 matlab.ui.control.Lamp
        Lamp_6                 matlab.ui.control.Lamp
        Lamp_5                 matlab.ui.control.Lamp
        Lamp_4                 matlab.ui.control.Lamp
        Lamp_3                 matlab.ui.control.Lamp
        Lamp_2                 matlab.ui.control.Lamp
        Lamp                   matlab.ui.control.Lamp
        ManualSelectPanel      matlab.ui.container.Panel
        Circle8Label           matlab.ui.control.Label
        Circle7Label           matlab.ui.control.Label
        Circle6Label           matlab.ui.control.Label
        Circle5Label           matlab.ui.control.Label
        Circle4Label           matlab.ui.control.Label
        Circle3Label           matlab.ui.control.Label
        Circle2Label           matlab.ui.control.Label
        Circle1Label           matlab.ui.control.Label
        DropDown_8             matlab.ui.control.DropDown
        DropDown_7             matlab.ui.control.DropDown
        DropDown_6             matlab.ui.control.DropDown
        DropDown_5             matlab.ui.control.DropDown
        DropDown_4             matlab.ui.control.DropDown
        DropDown_3             matlab.ui.control.DropDown
        DropDown_2             matlab.ui.control.DropDown
        DropDown               matlab.ui.control.DropDown
        Image                  matlab.ui.control.Image
    end

    
    properties (Access = public)
        board_state; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            GUindex{1,2} = value;
            if value == "blank"
                app.Lamp.Color = 'white';
            else
                app.Lamp.Color = value;
            end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            GUindex{2,2} = value;
            if value == "blank"
                app.Lamp_2.Color = 'white';
            else
                app.Lamp_2.Color = value;
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            GUindex{3,2} = value;
            if value == "blank"
                app.Lamp_3.Color = 'white';
            else
                app.Lamp_3.Color = value;
            end
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            value = app.DropDown_4.Value;
            GUindex{4,2} = value;
            if value == "blank"
                app.Lamp_4.Color = 'white';
            else
                app.Lamp_4.Color = value;
            end
        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            value = app.DropDown_5.Value;
            GUindex{5,2} = value;
            if value == "blank"
                app.Lamp_5.Color = 'white';
            else
                app.Lamp_5.Color = value;
            end
        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            value = app.DropDown_6.Value;
            GUindex{6,2} = value;
            if value == "blank"
                app.Lamp_6.Color = 'white';
            else
                app.Lamp_6.Color = value;
            end
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            value = app.DropDown_7.Value;
            GUindex{7,2} = value;
            if value == "blank"
                app.Lamp_7.Color = 'white';
            else
                app.Lamp_7.Color = value;
            end
        end

        % Value changed function: DropDown_8
        function DropDown_8ValueChanged(app, event)
            value = app.DropDown_8.Value;
            GUindex{8,2} = value;
            if value == "blank"
                app.Lamp_8.Color = 'white';
            else
                app.Lamp_8.Color = value;
            end
        end

        % Button pushed function: ReadBoardButton
        function ReadBoardButtonPushed(app, event)
            camera              = evalin('base','camera');
            state_fname         = evalin('base','state_fname');
            bg_filename         = evalin('base','bg_filename');
            difference_fname    = evalin('base','difference_fname');
            color_iso_bin       = evalin('base','color_iso_bin');
            color_thresh        = evalin('base','color_thresh');
            
            snapshot(camera); %first shot usually sucks, so waste it
            save_new_image_f(state_fname,camera);
            new_state = imread(state_fname);
            BG_img = imread(bg_filename);

            diff = BG_img - new_state;
            imwrite(diff,difference_fname);

            color_isolated_img = isolate_colors_f(difference_fname,color_thresh,state_fname);
            imwrite(im2bw(color_isolated_img,0.1),color_iso_bin);
            o_p_img = further_filter_f(color_iso_bin);

            gameState = image_analyze_f(o_p_img,state_fname);
            gameState = del_deup_f(gameState);

            app.Lamp.Color='White';
            app.Lamp_2.Color='White';
            app.Lamp_3.Color='White';
            app.Lamp_4.Color='White';
            app.Lamp_5.Color='White';
            app.Lamp_6.Color='White';
            app.Lamp_7.Color='White';
            app.Lamp_8.Color='White';
            for i=1:gameState(1).Num_of_Shapes
                if(gameState(i).Angle < 40)
                    app.Lamp.Color = gameState(i).color;
                elseif( 40 <= gameState(i).Angle && gameState(i).Angle < 90)
                    app.Lamp_2.Color = gameState(i).color;
                elseif( 90 <= gameState(i).Angle && gameState(i).Angle < 140)
                    app.Lamp_3.Color = gameState(i).color;
                elseif( 140 <= gameState(i).Angle && gameState(i).Angle < 180)
                    app.Lamp_4.Color = gameState(i).color;
                elseif( 180 <= gameState(i).Angle && gameState(i).Angle < 220)
                    app.Lamp_5.Color = gameState(i).color;
                elseif( 220 <= gameState(i).Angle && gameState(i).Angle < 270)
                    app.Lamp_6.Color = gameState(i).color;
                elseif( 270 <= gameState(i).Angle && gameState(i).Angle < 320)
                    app.Lamp_7.Color = gameState(i).color;
                else
                    app.Lamp_8.Color = gameState(i).color;
                end
            end
            app.board_state = fill_state_arrary_f(gameState);
        end

        % Button pushed function: ArrangeBoardButton
        function ArrangeBoardButtonPushed(app, event)
           %app.board_state;
%            user_board(1).color = {app.DropDown.Value,app.DropDown_2.Value,app.DropDown_3.Value,...
%                                app.DropDown_4.Value,app.DropDown_5.Value,app.DropDown_6.Value,...
%                                app.DropDown_7.Value,app.DropDown_8.Value} ;
%            user_board(1).angle = {40,0,-45,-90,-135,-180,-225,-270};

           %user_board = struct("color", [[app.DropDown.Value],app.DropDown_2.Value, app.DropDown_3.Value,app.DropDown_4.Value ...
           %     app.DropDown_5.Value,app.DropDown_6.Value,app.DropDown_7.Value,app.DropDown_8.Value], "angle", [40,0,-45,-90,-135,-180,-225,-270]);
           user_board = [struct('color',app.DropDown.Value,'angle',40),struct('color',app.DropDown_2.Value,'angle',0),...
               struct('color',app.DropDown_3.Value,'angle',-45),struct('color',app.DropDown_4.Value,'angle',-90),...
               struct('color',app.DropDown_5.Value,'angle',-135),struct('color',app.DropDown_6.Value,'angle',-180),...
               struct('color',app.DropDown_7.Value,'angle',-225),struct('color',app.DropDown_8.Value,'angle',-270)];

           motor_input      = evalin('base','motor_input');
           actuator_input   = evalin('base','actuator_input');
           emag_input       = evalin('base','motor_input');

           sorting_algorithm(app.board_state,user_board,motor_input,emag_input,actuator_input);
        end

        % Button pushed function: BackgroundImageButton
        function BackgroundImageButtonPushed(app, event)
            camera = evalin('base','camera');
            bg_filename = evalin('base','bg_filename');
            snapshot(camera);  %first shot usually sucks, so waste it
            save_new_image_f(bg_filename,camera);
        end

        % Value changed function: startmotorCheckBox
        function startmotorCheckBoxValueChanged(app, event)
            value = app.startmotorCheckBox.Value;
            SIM_file = evalin('base','SIM_file');
            if value == 1
                motor_model_updated_F21_22b %replace with run statement and see if works
                set_param(SIM_file, 'SimulationCommand', 'start');     %start simulation
            else
                set_param(SIM_file, 'SimulationCommand', 'stop');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Gameboard.JPG';
            app.ManualSelectPanel = uipanel(app.UIFigure);
            app.ManualSelectPanel.Title = 'Manual Select';
            app.DropDown = uidropdown(app.ManualSelectPanel);
            app.DropDown.Items = {'red', 'blue', 'green', 'yellow', 'blank'};
            app.DropDown.Editable = 'on';
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'blank';
            app.DropDown_2 = uidropdown(app.ManualSelectPanel);
            app.DropDown_2.Items = {'red', 'blue', 'green', 'yellow', 'blank'};
            app.DropDown_2.Editable = 'on';
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'blank';
            app.DropDown_3 = uidropdown(app.ManualSelectPanel);
            app.DropDown_3.Items = {'red', 'blue', 'green', 'yellow', 'blank'};
            app.DropDown_3.Editable = 'on';
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'blank';
            app.DropDown_4 = uidropdown(app.ManualSelectPanel);
            app.DropDown_4.Items = {'red', 'blue', 'green', 'yellow', 'blank'};
            app.DropDown_4.Editable = 'on';
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'blank';
            app.DropDown_5 = uidropdown(app.ManualSelectPanel);
            app.DropDown_5.Items = {'red', 'blue', 'green', 'yellow', 'blank'};
            app.DropDown_5.Editable = 'on';
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.DropDown_5.Value = 'blank';
            app.DropDown_6 = uidropdown(app.ManualSelectPanel);
            app.DropDown_6.Items = {'red', 'blue', 'green', 'yellow', 'blank'};
            app.DropDown_6.Editable = 'on';
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Value = 'blank';
            app.DropDown_7 = uidropdown(app.ManualSelectPanel);
            app.DropDown_7.Items = {'red', 'blue', 'green', 'yellow', 'blank'};
            app.DropDown_7.Editable = 'on';
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_7.Value = 'blank';
            app.DropDown_8 = uidropdown(app.ManualSelectPanel);
            app.DropDown_8.Items = {'red', 'blue', 'green', 'yellow', 'blank'};
            app.DropDown_8.Editable = 'on';
            app.DropDown_8.ValueChangedFcn = createCallbackFcn(app, @DropDown_8ValueChanged, true);
            app.DropDown_8.Value = 'blank';
            app.Circle1Label = uilabel(app.ManualSelectPanel);
            app.Circle1Label.Text = 'Circle 1';
            app.Circle2Label = uilabel(app.ManualSelectPanel);
            app.Circle2Label.Text = 'Circle 2';
            app.Circle3Label = uilabel(app.ManualSelectPanel);
            app.Circle3Label.Text = 'Circle 3';
            app.Circle4Label = uilabel(app.ManualSelectPanel);
            app.Circle4Label.Text = 'Circle 4';
            app.Circle5Label = uilabel(app.ManualSelectPanel);
            app.Circle5Label.Text = 'Circle 5';
            app.Circle6Label = uilabel(app.ManualSelectPanel);
            app.Circle6Label.Text = 'Circle 6';
            app.Circle7Label = uilabel(app.ManualSelectPanel);
            app.Circle7Label.Text = 'Circle 7';
            app.Circle8Label = uilabel(app.ManualSelectPanel);
            app.Circle8Label.Text = 'Circle 8';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            app.Lamp_5 = uilamp(app.UIFigure);
            app.Lamp_6 = uilamp(app.UIFigure);
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '8';
            app.ReadBoardButton = uibutton(app.UIFigure, 'push');
            app.ReadBoardButton.ButtonPushedFcn = createCallbackFcn(app, @ReadBoardButtonPushed, true);
            app.ReadBoardButton.Text = 'Read Board';
            app.ArrangeBoardButton = uibutton(app.UIFigure, 'push');
            app.ArrangeBoardButton.ButtonPushedFcn = createCallbackFcn(app, @ArrangeBoardButtonPushed, true);
            app.ArrangeBoardButton.Text = 'Arrange Board';
            app.BackgroundImageButton = uibutton(app.UIFigure, 'push');
            app.BackgroundImageButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundImageButtonPushed, true);
            app.BackgroundImageButton.Text = 'Background Image';
            app.startmotorCheckBox = uicheckbox(app.UIFigure);
            app.startmotorCheckBox.ValueChangedFcn = createCallbackFcn(app, @startmotorCheckBoxValueChanged, true);
            app.startmotorCheckBox.Text = 'start motor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end